classdef EstrousNetPlotting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Panel_4         matlab.ui.container.Panel
        FinishButton    matlab.ui.control.Button
        PreviousButton  matlab.ui.control.Button
        NextButton      matlab.ui.control.Button
        Panel_3         matlab.ui.container.Panel
        UIAxes3         matlab.ui.control.UIAxes
        UIAxes2         matlab.ui.control.UIAxes
        Panel_2         matlab.ui.container.Panel
        EditField_3     matlab.ui.control.EditField
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        classifier % EstrousNetClassifier.m
        curr_idx % Current test image number
        seq_flag % Flag for cyclicity predictions
        label_changed_idx % index of where labels were changed
        cytology_plot
        net_probability_plot
        cyclicity_plot
    end
    
    methods (Access = public)
        function plotClassificationResults(app)
            filenames = app.classifier.rawImages.Files;
            curr_pathname = filenames{app.curr_idx};
            [~,fname,ext] = fileparts(curr_pathname);
            curr_fname = strcat(fname,ext);
            set(app.EditField_3, 'Value', curr_fname) % display current filename under image

            % change data on all 3 plots
            I = importdata(app.classifier.rawImages.Files{app.curr_idx});
            if isstruct(I)
                I = I.cdata; % if importing structural pngs
            end
            if size(I,3) > 3
                I = I(:,:,1:3); % if importing 4D tiff
            end
            if length(I) > 1500 % otherwise the image gets pixelated
                I = imresize(I,0.1); % reduce size of image for quicker plotting
            end
            app.cytology_plot.CData = I;
            Ypred = app.classifier.finalLabels(app.curr_idx);
            title(app.UIAxes,{'\fontsize{15}\color{white}Net Prediction: '...
                '\fontsize{14}\color{white}' + string(Ypred)})% + ", " + num2str(100*max(app.classifier.labelProbabilities(app.curr_idx,:)),3) + "%"});
            % show changed labels if labels were changed
            if sum(app.label_changed_idx) > 0
                curr_labelChange = app.label_changed_idx(app.curr_idx);
                if curr_labelChange
                    title(app.UIAxes,{'\fontsize{15}\color{white}Cyclicity Prediction: '...
                        '\fontsize{12}\color{white}' + string(app.classifier.finalLabels(app.curr_idx)) + ', changed in mismatch GUI'}) % replot title when changed
                end
            end

            % get and plot confidence index
            % c_string = num2str(app.classifier.confidence_index(app.curr_idx));
            % subtitle(app.UIAxes2,strcat('\fontsize{12}\color{white}CI = ',c_string))%(1:4)))
            % 
            % app.net_probability_plot.YData = app.classifier.labelProbabilities(app.curr_idx,:);

            if app.seq_flag
                num_cycle_labels = regexprep(cellstr(app.classifier.cyclicityLabels),{'diestrus','proestrus','metestrus','estrus'},...
                    {'1','2','4','3'}); % convert categorical stages from cycle to numerical indices
                num_cycle_labels = str2num(cell2mat(num_cycle_labels)); % convert to double

                app.cyclicity_plot.XData = app.curr_idx;
                app.cyclicity_plot.YData = num_cycle_labels(app.curr_idx); % just change current marker
            end
            app.update();
        end
    end
    
    methods (Access = private)
        
        function update(app)
            if app.curr_idx == length(app.classifier.netLabels) % Next button disabled when you're at last image
                app.NextButton.Enable = 'off';
            else
                app.NextButton.Enable = 'on'; % enabled otherwise
            end
            if app.curr_idx == 1
                app.PreviousButton.Enable = 'off'; % Prev button disabled when you're at 1st image
            else
                app.PreviousButton.Enable = 'on';  % enabled otherwise
            end
            drawnow();
        end
        
        
        function initialize2(app)
            filenames = app.classifier.rawImages.Files;
            curr_pathname = filenames{app.curr_idx};
            [~,fname,ext] = fileparts(curr_pathname);
            curr_fname = strcat(fname,ext);
            set(app.EditField_3, 'Value', curr_fname) % display current filename under image
            
            %% Plot EstrousNet classification results
            I = importdata(app.classifier.rawImages.Files{app.curr_idx});
            if isstruct(I)
                I = I.cdata; % if importing structural pngs
            end
            if size(I,3) > 3
                I = I(:,:,1:3); % if importing 4D tiff
            end
            if length(I) > 1500 % otherwise the image gets pixelated
                I = imresize(I,0.1); % reduce size of image for quicker plotting
            end
            app.cytology_plot = imagesc(I,'Parent',app.UIAxes); % display image before processing
            axis(app.UIAxes, 'image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            Ypred = app.classifier.finalLabels(app.curr_idx);
            title(app.UIAxes,{'\fontsize{15}\color{white}Net Prediction: '...
                '\fontsize{14}\color{white}' + string(Ypred)});% + ", " + num2str(100*max(app.classifier.labelProbabilities(app.curr_idx,:)),3) + "%"});

            % get and plot confidence index
            % c_string = num2str(app.classifier.confidence_index(app.curr_idx));
            % subtitle(app.UIAxes2,strcat('\fontsize{12}\color{white}CI = ',c_string))%(1:4)))
            % 
            % classNames = unique(app.classifier.finalLabels); % all lables included in classification
            % classNames = classNames([1;4;2;3]); % switch 2nd and last dims
            % scoreTop = app.classifier.labelProbabilities(app.curr_idx,:);
            % app.net_probability_plot = barh(app.UIAxes2,scoreTop,'FaceColor','#f78278');
            % title(app.UIAxes2,'\fontsize{14}\color{white}Stage Probability Predictions');
            % xlabel(app.UIAxes2,'\fontsize{14}\color{white}Probability')
            % xlim(app.UIAxes2,[0 1])
            % yticklabels(app.UIAxes2,strcat('\fontsize{13}\color{white}',classNames))

            
            %% Plot linear regression classification results
            if app.seq_flag % if images were taken sequentially
                classNames = {'diestrus','proestrus','metestrus','estrus'};
                num_net_labels = regexprep(cellstr(app.classifier.finalLabels),{'diestrus/metestrus','diestrus/proestrus','proestrus/estrus','estrus/metestrus',...
                    'diestrus','proestrus','metestrus','estrus'},...
                {'0.5','1.5','2.5','3.5','1.0','2.0','4.0','3.0'});  % convert categorical stages from net to numerical indices
                num_net_labels = str2num(cell2mat(num_net_labels)); % convert to double
                num_cycle_labels = regexprep(cellstr(app.classifier.cyclicityLabels),{'diestrus','proestrus','metestrus','estrus'},...
                    {'1','2','4','3'}); % convert categorical stages from cycle to numerical indices
                num_cycle_labels = str2num(cell2mat(num_cycle_labels)); % convert to double
                x = [1:length(filenames)]';
                plot(app.UIAxes3,num_cycle_labels,'LineWidth',5,'Color','#f78278');
                hold(app.UIAxes3,'on')
                scatter(app.UIAxes3,x,num_net_labels,'filled','MarkerEdgeColor',[0.24 0.58 0.71],'MarkerFaceColor',[0.24 0.58 0.71]);
                hold(app.UIAxes3,'on')
                app.cyclicity_plot = plot(app.UIAxes3,app.curr_idx,num_cycle_labels(app.curr_idx),'r*','LineWidth',11); %% emphasize current point
                hold(app.UIAxes3,'off')
                title(app.UIAxes3,'\fontsize{14}\color{white}Stage Fitting Predictions')
                xlabel(app.UIAxes3,'\fontsize{14}\color{white}Test Image')
                set(app.UIAxes3,'XTick',1:1:length(x)) % show all x ticks
                xlim(app.UIAxes3,[1 length(filenames)])
                yticks(app.UIAxes3, 1:length(classNames))
                yticklabels(app.UIAxes3,strcat('\fontsize{13}\color{white}',classNames))
                ylim(app.UIAxes3, [0.5 length(classNames)+0.5]) % give y axis 0.5 buffer
                legend(app.UIAxes3,'cycle prediction','net prediction','current image','Position',[0.17 0.35 0.07 0.05]);
                
                % show changed labels if labels were changed
                if sum(app.label_changed_idx) > 0
                    curr_labelChange = app.label_changed_idx(app.curr_idx);
                    if curr_labelChange
                        title(app.UIAxes,{'\fontsize{15}\color{white}Cyclicity Prediction: '...
                            '\fontsize{14}\color{white}' + string(app.classifier.finalLabels(app.curr_idx)) + ', changed in mismatch GUI'}) % replot title when changed
                    end
                end
            end
            drawnow();
            figure(app.UIFigure)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, classifier, seq_flag, label_changed_idx, confidence_index)
            if nargin < 2 || isempty(seq_flag)
                seq_flag = false;
            end
            if nargin < 3 || isempty(label_changed_idx)
                label_changed_idx = zeros(length(classifier.netLabels),1);
            end
            % if nargin < 4 || isempty(confidence_index) % calculate confidence index if not already assigned
            %     confidence_index = zeros(size(classifier.labelProbabilities,1),1);
            %     for ii = 1:size(classifier.labelProbabilities,1)
            %         sortedScore = sort(classifier.labelProbabilities(ii,:));
            %         confidence_index(ii,1) = (sortedScore(end)-sortedScore(end-1))/(sortedScore(end)+sortedScore(end-1)); % calculate CI and put in vector
            %     end
            % end
            app.classifier = classifier;
            % app.classifier.confidence_index = confidence_index; % put confidence indices in classifier structure
            app.curr_idx = 1; % set to 1 initially
            app.seq_flag = seq_flag;
            app.label_changed_idx = label_changed_idx;
            app.PreviousButton.Enable = 'off'; % Previous button begins off
            if ~app.seq_flag
                app.UIAxes3.Visible = 'off'; % make axis invisible if images were not sampled sequentially
            end
            app.initialize2();
            app.update();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.curr_idx = app.curr_idx + 1;
            app.curr_idx = min(app.curr_idx, length(app.classifier.netLabels)); % prevent curr_idx from passing bounds
            app.plotClassificationResults();
            app.update();
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.curr_idx = app.curr_idx - 1;
            app.curr_idx = max(app.curr_idx, 1); % prevent curr_idx from passing bounds
            app.plotClassificationResults();
            app.update();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key; % enable keyboard functionality for next and prev buttons
            switch key
                case 'rightarrow'
                    NextButtonPushed(app, event);
                case 'leftarrow'
                    PreviousButtonPushed(app, event);
            end
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            close(app.UIFigure) % close figure
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.Panel_4);
            app.UIAxes = uiaxes(app.Panel_2);
            zlabel(app.UIAxes, 'Z')
            app.EditField_3 = uieditfield(app.Panel_2, 'text');
            app.Panel_3 = uipanel(app.Panel_4);
            app.UIAxes2 = uiaxes(app.Panel_3);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.Panel_3);
            zlabel(app.UIAxes3, 'Z')
            app.NextButton = uibutton(app.Panel_4, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.Panel_4, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.FinishButton = uibutton(app.Panel_4, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EstrousNetPlotting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end