classdef EstrousNetTrainNewNet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel_3                matlab.ui.container.Panel
        StatusEditField        matlab.ui.control.EditField
        StatusLabel            matlab.ui.control.Label
        Panel_2                matlab.ui.container.Panel
        ReflectionLabel        matlab.ui.control.Label
        Switch_2               matlab.ui.control.Switch
        Lamp                   matlab.ui.control.Lamp
        ScalingLabel           matlab.ui.control.Label
        Switch_3               matlab.ui.control.Switch
        Lamp_2                 matlab.ui.control.Lamp
        RotationLabel          matlab.ui.control.Label
        Switch_4               matlab.ui.control.Switch
        Lamp_3                 matlab.ui.control.Lamp
        TranslationLabel       matlab.ui.control.Label
        Switch                 matlab.ui.control.Switch
        Lamp_4                 matlab.ui.control.Lamp
        SetyouraugmentationparametersLabel  matlab.ui.control.Label
        iButton                matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        SelectFoldersButton    matlab.ui.control.Button
        EditField              matlab.ui.control.EditField
        EditField_2            matlab.ui.control.EditField
        ChooseyourtrainingandvalidationimagesLabel  matlab.ui.control.Label
        iButton_2              matlab.ui.control.Button
        SequentialInfoPanel    matlab.ui.container.Panel
        SequentialInfoLabel    matlab.ui.control.Label
        TRAINESTROUSNETButton  matlab.ui.control.Button
        SequentialInfoPanel_2  matlab.ui.container.Panel
        SequentialInfoLabel_2  matlab.ui.control.Label
        Panel_4                matlab.ui.container.Panel
        SelectyourbasearchitectureLabel  matlab.ui.control.Label
        SelectanetDropDown     matlab.ui.control.DropDown
        iButton_3              matlab.ui.control.Button
        SequentialInfoPanel_3  matlab.ui.container.Panel
        SequentialInfoLabel_3  matlab.ui.control.Label
    end

    
    properties (Access = private)
        trainer % EstrousNetTrainer
        augmenter_opts % options for augmented datastore
        ref_inpt % reflection parameter input
        scale_inpt % scaling parameter input
        rot_inpt % rotation parameter input
        trans_inpt % translation parameter input
        numberiButtonPresses % number of times augmentation info button is pressed
        numberiButton2Presses % number of times folder info button is pressed
        numberiButton3Presses % number of times net info button is pressed
    end
    
    methods (Access = private)
        
        
        function checkReadiness(app)
            if ~isempty(app.trainer.trainFolder) && ~isempty(app.trainer.validationFolder) % ready to go
                app.TRAINESTROUSNETButton.Enable = 'on';
                app.displayMsg('Ready to run.')
            else
                app.TRAINESTROUSNETButton.Enable = 'off';
            end
        end
        
        function displayMsg(app,msg)
            set(app.StatusEditField, 'Value', msg)
            drawnow;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.trainer = EstrousNetTrainer();
            app.SequentialInfoPanel.Visible = 'off'; % info panel initially invisible
            app.SequentialInfoPanel_2.Visible = 'off';
            app.SequentialInfoPanel_3.Visible = 'off';
            app.SelectanetDropDown.Value = 'resnet50'; % set default architechture to ResNet
            app.numberiButtonPresses = 0; % start counting number of button presses
            app.numberiButton2Presses = 0;
            app.numberiButton3Presses = 0;
            app.augmenter_opts.ref_inpt = true; % instantiate augmentation parameters
            app.augmenter_opts.scale_inpt = [0.5 4];
            app.augmenter_opts.rot_inpt = [-10 10];
            app.augmenter_opts.trans_inpt = [-30 30];
            figure(app.UIFigure)
            app.checkReadiness();
        end

        % Button pushed function: SelectFoldersButton
        function SelectFoldersButtonPushed(app, event)
            app.displayMsg('Choosing your training folder:');
            app.trainer.getTrainFolder();
            app.EditField.Value = app.trainer.trainFolder;
            figure(app.UIFigure)
            app.displayMsg('Training folder selected.');
            pause(1); % long enough to read message
            if app.trainer.trainFolder == 0
                app.displayMsg('Choosing training folder cancelled.')
                return
            end
            app.displayMsg('Choosing your validation folder:');
            app.trainer.getValidationFolder();
            app.EditField_2.Value = app.trainer.validationFolder;
            figure(app.UIFigure)
            app.displayMsg('Validation folder selected.');
            pause(1); % long enough to read message
            if app.trainer.validationFolder == 0
                app.displayMsg('Choosing validation folder cancelled.')
                return
            end
            app.checkReadiness();
        end

        % Button pushed function: TRAINESTROUSNETButton
        function TRAINESTROUSNETButtonPushed(app, event)
            fig = uifigure;
            selection = uiconfirm(fig,'Process may take hours to days; do you want to continue?',...
                'WARNING','Icon','warning');
            switch selection
                case 'OK'
                app.displayMsg('Augmenting data...')
                app.trainer.setAugmentationOptions(app.augmenter_opts);
                app.trainer.setNetwork(app.SelectanetDropDown.Value);
                app.displayMsg('Setting network options...')
                app.trainer.setTrainingOptions(false);
                app.displayMsg('Training in progress...')
                app.trainer.train(true);
                app.displayMsg('Training complete! Network saved to current directory.')
                figure(app.UIFigure)
                otherwise
                app.displayMsg('Operation terminated.')
                close all
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if strcmp(value,"False")
                app.Lamp.Color = 'r';
                app.augmenter_opts.ref_inpt = false;
            else
                app.Lamp.Color = 'g';
                app.augmenter_opts.ref_inpt = true;
            end
            app.checkReadiness();
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            if strcmp(value,"False")
                app.Lamp_2.Color = 'r';
                app.augmenter_opts.scale_inpt = [0 0];
            else
                app.Lamp_2.Color = 'g';
                app.augmenter_opts.scale_inpt = [0.5 4];
            end
            app.checkReadiness();
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            if strcmp(value,"False")
                app.Lamp_3.Color = 'r';
                app.augmenter_opts.rot_inpt = [0 0];
            else
                app.Lamp_3.Color = 'g';
                app.augmenter_opts.rot_inpt = [-10 10];
            end
            app.checkReadiness();
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,"False")
                app.Lamp_4.Color = 'r';
                app.augmenter_opts.trans_inpt = [0 0];
            else
                app.Lamp_4.Color = 'g';
                app.augmenter_opts.trans_inpt = [-30 30];
            end
            app.checkReadiness();
        end

        % Button pushed function: iButton
        function iButtonPushed(app, event)
            app.numberiButtonPresses = app.numberiButtonPresses+1;
            isodd = rem(app.numberiButtonPresses, 2) ~= 0;
            if isodd
                app.SequentialInfoPanel.Visible = 'on';
            else
                app.SequentialInfoPanel.Visible = 'off';
            end
            app.checkReadiness();
        end

        % Button pushed function: iButton_2
        function iButton_2Pushed(app, event)
            app.numberiButton2Presses = app.numberiButton2Presses+1;
            isodd = rem(app.numberiButton2Presses, 2) ~= 0;
            if isodd
                app.SequentialInfoPanel_2.Visible = 'on';
            else
                app.SequentialInfoPanel_2.Visible = 'off';
            end
            app.checkReadiness();
        end

        % Button pushed function: iButton_3
        function iButton_3Pushed(app, event)
            app.numberiButton3Presses = app.numberiButton3Presses+1;
            isodd = rem(app.numberiButton3Presses, 2) ~= 0;
            if isodd
                app.SequentialInfoPanel_3.Visible = 'on';
            else
                app.SequentialInfoPanel_3.Visible = 'off';
            end
            app.checkReadiness();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_3 = uipanel(app.UIFigure);
            app.StatusEditField = uieditfield(app.Panel_3, 'text');
            app.StatusEditField.Value = 'Idle';
            app.StatusLabel = uilabel(app.Panel_3);
            app.StatusLabel.Text = 'Status:';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ReflectionLabel = uilabel(app.Panel_2);
            app.ReflectionLabel.Text = 'Reflection';
            app.Switch_2 = uiswitch(app.Panel_2, 'slider');
            app.Switch_2.Items = {'False', 'True'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'True';
            app.Lamp = uilamp(app.Panel_2);
            app.ScalingLabel = uilabel(app.Panel_2);
            app.ScalingLabel.Text = 'Scaling';
            app.Switch_3 = uiswitch(app.Panel_2, 'slider');
            app.Switch_3.Items = {'False', 'True'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'True';
            app.Lamp_2 = uilamp(app.Panel_2);
            app.RotationLabel = uilabel(app.Panel_2);
            app.RotationLabel.Text = 'Rotation';
            app.Switch_4 = uiswitch(app.Panel_2, 'slider');
            app.Switch_4.Items = {'False', 'True'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Value = 'True';
            app.Lamp_3 = uilamp(app.Panel_2);
            app.TranslationLabel = uilabel(app.Panel_2);
            app.TranslationLabel.Text = 'Translation';
            app.Switch = uiswitch(app.Panel_2, 'slider');
            app.Switch.Items = {'False', 'True'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'True';
            app.Lamp_4 = uilamp(app.Panel_2);
            app.SetyouraugmentationparametersLabel = uilabel(app.Panel_2);
            app.SetyouraugmentationparametersLabel.Text = 'Set your augmentation parameters';
            app.iButton = uibutton(app.Panel_2, 'push');
            app.iButton.ButtonPushedFcn = createCallbackFcn(app, @iButtonPushed, true);
            app.iButton.Text = 'i';
            app.Panel = uipanel(app.UIFigure);
            app.SelectFoldersButton = uibutton(app.Panel, 'push');
            app.SelectFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFoldersButtonPushed, true);
            app.SelectFoldersButton.Text = 'Select Folders';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.ChooseyourtrainingandvalidationimagesLabel = uilabel(app.Panel);
            app.ChooseyourtrainingandvalidationimagesLabel.Text = 'Choose your training and validation images';
            app.iButton_2 = uibutton(app.Panel, 'push');
            app.iButton_2.ButtonPushedFcn = createCallbackFcn(app, @iButton_2Pushed, true);
            app.iButton_2.Text = 'i';
            app.SequentialInfoPanel = uipanel(app.UIFigure);
            app.SequentialInfoLabel = uilabel(app.SequentialInfoPanel);
            app.SequentialInfoLabel.Text = 'Augmentation parameters have been preset to maximize validation accuracy. However, reducing agumentation parameters may increase training speed.';
            app.TRAINESTROUSNETButton = uibutton(app.UIFigure, 'push');
            app.TRAINESTROUSNETButton.ButtonPushedFcn = createCallbackFcn(app, @TRAINESTROUSNETButtonPushed, true);
            app.TRAINESTROUSNETButton.Text = 'TRAIN ESTROUSNET';
            app.SequentialInfoPanel_2 = uipanel(app.UIFigure);
            app.SequentialInfoLabel_2 = uilabel(app.SequentialInfoPanel_2);
            app.SequentialInfoLabel_2.Text = 'Training and validation subfolders must be labeled by stage, i.e. ''diestrus'', ''proestrus'', and ''estrus''.';
            app.Panel_4 = uipanel(app.UIFigure);
            app.SelectyourbasearchitectureLabel = uilabel(app.Panel_4);
            app.SelectyourbasearchitectureLabel.Text = 'Select your base architecture';
            app.SelectanetDropDown = uidropdown(app.Panel_4);
            app.SelectanetDropDown.Items = {'resnet50', 'inceptionv3', 'vgg19', 'mobilenetv2'};
            app.SelectanetDropDown.Value = 'resnet50';
            app.iButton_3 = uibutton(app.Panel_4, 'push');
            app.iButton_3.ButtonPushedFcn = createCallbackFcn(app, @iButton_3Pushed, true);
            app.iButton_3.Text = 'i';
            app.SequentialInfoPanel_3 = uipanel(app.UIFigure);
            app.SequentialInfoLabel_3 = uilabel(app.SequentialInfoPanel_3);
            app.SequentialInfoLabel_3.Text = 'ResNet50 yields the highest accuracy on the EstrousBank training set, however other networks can be used if desired.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EstrousNetTrainNewNet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end