classdef EstrousNetGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SequentialInfoPanel            matlab.ui.container.Panel
        SequentialInfoLabel            matlab.ui.control.Label
        NetInfoPanel                   matlab.ui.container.Panel
        NetInfoLabel                   matlab.ui.control.Label
        ImageSelectionPanel            matlab.ui.container.Panel
        ChooseyourtestimagesLabel      matlab.ui.control.Label
        SelectedFolderLabel            matlab.ui.control.EditField
        SelectafolderButton            matlab.ui.control.Button
        ExportresultsButton            matlab.ui.control.Button
        RUNESTROUSNETButton            matlab.ui.control.Button
        SequentialSamplePanel          matlab.ui.container.Panel
        Lamp                           matlab.ui.control.Lamp
        Switch                         matlab.ui.control.Switch
        iButton                        matlab.ui.control.Button
        DidyousamplesequentiallyLabel  matlab.ui.control.Label
        StatusBarPanel                 matlab.ui.container.Panel
        StatusLabel                    matlab.ui.control.Label
        StatusEditField                matlab.ui.control.EditField
        NetSelectionPanel              matlab.ui.container.Panel
        Lamp_2                         matlab.ui.control.Lamp
        trainSwitch                    matlab.ui.control.Switch
        iButton_2                      matlab.ui.control.Button
        DoyouwanttotrainanewnetworkADVANCEDUSERSONLYLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        trainer % EstrousNetTrainer
        classifier % EstrousNetClassifier
        net % Pretrained net
        seq_flag % Flag for whether or not the user sampled sequentially
        numberiButtonPresses % counts how many times the iButton was pushed
        numberiButton2Presses % number of times stage info button pushed
        mismatch_idx % vector of mismatched indices
    end
    
    methods (Access = private)
        
        function checkReadiness(app)
            if ~isempty(app.net) && ~isempty(app.classifier.testFolder) % ready to go
                app.RUNESTROUSNETButton.Enable = 'on';
                app.displayMsg('Ready to run.')
            else
                app.RUNESTROUSNETButton.Enable = 'off';
            end
        end
        
        function displayMsg(app, msg)
            set(app.StatusEditField, 'Value', msg)
            drawnow;
            pause(0.1); % allow the bar to update
        end
        
        
        function net = loadNet(app)
            chosen_net = 'PretrainedNet.mat'; % change if given new filename
            if ~isfile(which(chosen_net))
                app.displayMsg(sprintf('''%s'' cannot be found', chosen_net))
                net = [];
                return
            end
            
            app.displayMsg('Loading pretrained net...')
            net = importdata(chosen_net);
            app.net = net;
            app.displayMsg('Net import successful.')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            folder = fileparts(which('EstrousNetGUI.mlapp'));
            addpath(genpath(folder)); % add all subfolders of GUI to path
            app.trainer = EstrousNetTrainer();
            app.classifier = EstrousNetClassifier();
            app.SequentialInfoPanel.Visible = 'off';
            app.NetInfoPanel.Visible = 'off';
            app.numberiButtonPresses = 0;
            app.numberiButton2Presses = 0;
            app.checkReadiness();
            app.seq_flag = 1; % set to true by default
            loadNet(app); % load pretrained net
        end

        % Button pushed function: SelectafolderButton
        function SelectafolderButtonPushed(app, event)
            app.displayMsg('Choose your test folder: ')
            app.classifier.getTestFolder();
            if app.classifier.testFolder == 0
                app.displayMsg('Choosing test folder cancelled.')
                return
            end
            folder_contents = [dir(fullfile(app.classifier.testFolder, '*.jpg')); dir(fullfile(app.classifier.testFolder, '*.png'));...
                dir(fullfile(app.classifier.testFolder, '*.bmp')) dir(fullfile(app.classifier.testFolder, '*.tif'));...
                dir(fullfile(app.classifier.testFolder, '*.tiff'))];
            if isempty(folder_contents)
                app.displayMsg('ERROR: No images detected. Please select new folder.')
                return
            end
            app.SelectedFolderLabel.BackgroundColor = [1, 1, 1]; % update color
            app.SelectedFolderLabel.Value = app.classifier.testFolder;
            app.displayMsg('Test folder selected.');
            app.checkReadiness();
            figure(app.UIFigure)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.seq_flag = app.Switch.Value;
            if strcmp(app.seq_flag,"Yes")
                app.Lamp.Color = 'g';
                app.seq_flag = 1;
            else
                app.Lamp.Color = 'r';
                app.seq_flag = 0;
            end
            app.checkReadiness();
        end

        % Button pushed function: RUNESTROUSNETButton
        function RUNESTROUSNETButtonPushed(app, event)
            app.RUNESTROUSNETButton.Enable = 'off'; % disable button while classifier is running
            app.classifier.setNet(app.net);
            app.displayMsg('Preprocessing images...');
            
            d = dir(app.classifier.testFolder);
            dfolders = d([d(:).isdir]);
            dfolders = dfolders(~ismember({dfolders(:).name},{'.','..'})); % isolate subfolders in directory
            if~isempty(dfolders) % if subdirectories exist
                subdir = dfolders.name;
            else
                subdir = 'not yet processed';
            end
            if ~strcmp(subdir,'processedTestImages') % if processed folder does not already exist
                app.classifier.preprocessImages(); % preprocess test images
            else % if preprocessed images exist
                app.classifier.testFolder = strcat(app.classifier.testFolder,'\processedTestImages'); % change test folder to processed folder
            end
            
            aug_test_imds = app.classifier.prepareImageDatastore();
            app.displayMsg('Performing classifications...');
            app.classifier.getClassification(aug_test_imds);
            if app.seq_flag
                app.displayMsg('Generating cyclicity predictions...');
                app.classifier.generateCyclicityPredictions();
            end
            
            %% Plot results
            app.displayMsg('Plotting results...');
            if app.seq_flag % if images were taken sequentially
                % if net and cyclicity labels differ, let user choose which to accept
                app.mismatch_idx = zeros(1,length(app.classifier.cyclicityLabels));
                for ii = 1:length(app.classifier.cyclicityLabels)
                    curr_netLabel = app.classifier.netLabels(ii);
                    curr_cycleLabel = app.classifier.cyclicityLabels(ii);
                    if curr_netLabel ~= curr_cycleLabel
                        app.mismatch_idx(1,ii) = ii;
                    end
                end
                % remove zeros from array
                empties = find(app.mismatch_idx == 0);
                app.mismatch_idx(empties) = [];
                if sum(app.mismatch_idx) > 1 % if label vectors don't match
                    EstrousMismatchGUI(app.classifier,app.mismatch_idx);
                else
                    EstrousNetPlotting(app.classifier,app.seq_flag,[],[]);
                end
            else
                EstrousNetPlotting(app.classifier,app.seq_flag,[],[]);
            end
            app.displayMsg('Finished!');
            app.RUNESTROUSNETButton.Enable = 'on'; % re-enable button once classifier is finished
            app.ExportresultsButton.Enable = 'on';
        end

        % Button pushed function: iButton
        function iButtonPushed(app, event)
            app.numberiButtonPresses = app.numberiButtonPresses+1;
            isodd = rem(app.numberiButtonPresses, 2) ~= 0;
            if isodd
                app.SequentialInfoPanel.Visible = 'on';
            else
                app.SequentialInfoPanel.Visible = 'off';
            end
        end

        % Button pushed function: ExportresultsButton
        function ExportresultsButtonPushed(app, event)
            assignin('base', 'results', app.classifier)
            app.displayMsg('Variables created in base workspace.')
            fprintf('Variable ''results'' created in base workspace\n')
            figure(app.UIFigure)
        end

        % Button pushed function: iButton_2
        function iButton_2Pushed(app, event)
            app.numberiButton2Presses = app.numberiButton2Presses+1;
            isodd = rem(app.numberiButton2Presses, 2) ~= 0;
            if isodd
                app.NetInfoPanel.Visible = 'on';
            else
                app.NetInfoPanel.Visible = 'off';
            end
        end

        % Value changed function: trainSwitch
        function trainSwitchValueChanged(app, event)
            value = app.trainSwitch.Value;
            if strcmp(value,'Yes')
                app.Lamp_2.Color = 'g'; % turn lamp green
                EstrousNetTrainNewNet(); % run training GUI
            else
                app.Lamp_2.Color = 'r';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NetSelectionPanel = uipanel(app.UIFigure);
            app.DoyouwanttotrainanewnetworkADVANCEDUSERSONLYLabel = uilabel(app.NetSelectionPanel);
            app.DoyouwanttotrainanewnetworkADVANCEDUSERSONLYLabel.Text = 'Do you want to train a new network? (ADVANCED USERS ONLY)';
            app.iButton_2 = uibutton(app.NetSelectionPanel, 'push');
            app.iButton_2.ButtonPushedFcn = createCallbackFcn(app, @iButton_2Pushed, true);
            app.iButton_2.Text = 'i';
            app.trainSwitch = uiswitch(app.NetSelectionPanel, 'slider');
            app.trainSwitch.Items = {'No', 'Yes'};
            app.trainSwitch.ValueChangedFcn = createCallbackFcn(app, @trainSwitchValueChanged, true);
            app.trainSwitch.Value = 'No';
            app.Lamp_2 = uilamp(app.NetSelectionPanel);
            app.StatusBarPanel = uipanel(app.UIFigure);
            app.StatusEditField = uieditfield(app.StatusBarPanel, 'text');
            app.StatusEditField.Value = 'Idle';
            app.StatusLabel = uilabel(app.StatusBarPanel);
            app.StatusLabel.Text = 'Status:';
            app.SequentialSamplePanel = uipanel(app.UIFigure);
            app.DidyousamplesequentiallyLabel = uilabel(app.SequentialSamplePanel);
            app.DidyousamplesequentiallyLabel.Text = 'Did you sample sequentially?';
            app.iButton = uibutton(app.SequentialSamplePanel, 'push');
            app.iButton.ButtonPushedFcn = createCallbackFcn(app, @iButtonPushed, true);
            app.iButton.Text = 'i';
            app.Switch = uiswitch(app.SequentialSamplePanel, 'slider');
            app.Switch.Items = {'No', 'Yes'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Yes';
            app.Lamp = uilamp(app.SequentialSamplePanel);
            app.RUNESTROUSNETButton = uibutton(app.UIFigure, 'push');
            app.RUNESTROUSNETButton.ButtonPushedFcn = createCallbackFcn(app, @RUNESTROUSNETButtonPushed, true);
            app.RUNESTROUSNETButton.Enable = 'off';
            app.RUNESTROUSNETButton.Text = 'RUN ESTROUSNET';
            app.ExportresultsButton = uibutton(app.UIFigure, 'push');
            app.ExportresultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportresultsButtonPushed, true);
            app.ExportresultsButton.Enable = 'off';
            app.ExportresultsButton.Text = 'Export results';
            app.ImageSelectionPanel = uipanel(app.UIFigure);
            app.SelectafolderButton = uibutton(app.ImageSelectionPanel, 'push');
            app.SelectafolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectafolderButtonPushed, true);
            app.SelectafolderButton.Text = 'Select a folder';
            app.SelectedFolderLabel = uieditfield(app.ImageSelectionPanel, 'text');
            app.ChooseyourtestimagesLabel = uilabel(app.ImageSelectionPanel);
            app.ChooseyourtestimagesLabel.Text = 'Choose your test images';
            app.NetInfoPanel = uipanel(app.UIFigure);
            app.NetInfoLabel = uilabel(app.NetInfoPanel);
            app.NetInfoLabel.Text = 'Our pretrained network has been trained on EstrousBank. Users may choose to train the net with their own data.';
            app.SequentialInfoPanel = uipanel(app.UIFigure);
            app.SequentialInfoLabel = uilabel(app.SequentialInfoPanel);
            app.SequentialInfoLabel.Text = 'If test images were taken sequentially, EstrousNet will fit the images to an archetypal cycle. To determine sequentiality, files must be in the following format: "YYMMDD_AM/PM_ImageName", i.e. "210701_AM_Mouse1.jpg".';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EstrousNetGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end